classdef SinPWM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MadebySmitarekarSarthakNisalLabel  matlab.ui.control.Label
        PULSEWIDTHMODULATIONLabel      matlab.ui.control.Label
        InputControlPanel              matlab.ui.container.Panel
        FrequencyofModulatingWaveSpinner  matlab.ui.control.Spinner
        FrequencyofModulatingWaveSpinnerLabel  matlab.ui.control.Label
        FrequencyofCarrierWaveSpinner  matlab.ui.control.Spinner
        FrequencyofCarrierWaveSpinnerLabel  matlab.ui.control.Label
        SimulateButton                 matlab.ui.control.Button
        OutputGraphsPanel              matlab.ui.container.Panel
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            assignin("base","Fc",app.FrequencyofCarrierWaveSpinner.Value);
            assignin("base","Fm",app.FrequencyofModulatingWaveSpinner.Value);
            output = sim("D:\SY\Trim 6\AC\Modified project\SPWM.slx");
            plot(app.UIAxes,output.Waves.time,output.Waves.data);
            plot(app.UIAxes2,output.PWM.time,output.PWM.data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OutputGraphsPanel = uipanel(app.UIFigure);
            app.OutputGraphsPanel.Title = 'Output Graphs';
            app.UIAxes = uiaxes(app.OutputGraphsPanel);
            title(app.UIAxes, 'Carrier wave & Modulating Waves')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 0.1];
            app.UIAxes.YLim = [-0.5 1.5];
            app.UIAxes2 = uiaxes(app.OutputGraphsPanel);
            title(app.UIAxes2, 'PWM Waveform')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 0.1];
            app.UIAxes2.YLim = [-0.5 1.5];
            app.InputControlPanel = uipanel(app.UIFigure);
            app.InputControlPanel.Title = 'Input Control';
            app.SimulateButton = uibutton(app.InputControlPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.FrequencyofCarrierWaveSpinnerLabel = uilabel(app.InputControlPanel);
            app.FrequencyofCarrierWaveSpinnerLabel.Text = 'Frequency of Carrier Wave';
            app.FrequencyofCarrierWaveSpinner = uispinner(app.InputControlPanel);
            app.FrequencyofCarrierWaveSpinner.Limits = [0 Inf];
            app.FrequencyofModulatingWaveSpinnerLabel = uilabel(app.InputControlPanel);
            app.FrequencyofModulatingWaveSpinnerLabel.Text = 'Frequency of Modulating Wave';
            app.FrequencyofModulatingWaveSpinner = uispinner(app.InputControlPanel);
            app.FrequencyofModulatingWaveSpinner.Limits = [0 Inf];
            app.PULSEWIDTHMODULATIONLabel = uilabel(app.UIFigure);
            app.PULSEWIDTHMODULATIONLabel.Text = 'PULSE WIDTH MODULATION';
            app.MadebySmitarekarSarthakNisalLabel = uilabel(app.UIFigure);
            app.MadebySmitarekarSarthakNisalLabel.Text = 'Made by: Smit arekar & Sarthak Nisal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SinPWM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end